classdef CrystalCropperGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ResetPointsButton       matlab.ui.control.Button
        OutputFolderField       matlab.ui.control.EditField
        SavingFolderLabel       matlab.ui.control.Label
        SelectFolderButton      matlab.ui.control.Button
        SavedCounterLabel       matlab.ui.control.Label
        NextFrameButton         matlab.ui.control.Button
        PreviousFrameButton     matlab.ui.control.Button
        ConfirmSelectionButton  matlab.ui.control.Button
        FrameLabel              matlab.ui.control.Label
        CropCrystalButton       matlab.ui.control.Button
        FrameSlider             matlab.ui.control.Slider
        LoadVideoButton         matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

   properties (Access = private)
    VideoObject     % To hold the VideoReader object
    CroppedImage    % To store the currently cropped image
    TotalFrames     % To store the total number of frames
    VideoFileName   % To store the name of the video file
    RectangleROI    % To hold the interactive rectangle object
    SavedCrystalCount = 0; % Counter for saved crystals
    OutputFolderPath = ''; % To store the selected save destination
    SavedFrameIndex = 0;   % To number the output files (Frame_001, etc.)
    PolygonROIs = []; % Array to store all 4-point polygon objects
   end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ensure the confirm button is disabled on startup
            app.ConfirmSelectionButton.Enable = 'off';
            % Set a default output path when the app starts
            app.OutputFolderPath = fullfile(pwd, 'Cropped_Crystals'); % pwd is the current folder
            app.OutputFolderField.Value = app.OutputFolderPath;
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
   
            % Open file selection dialog for video files
            [file, path] = uigetfile({'*.mp4';'*.avi';'*.mov';'*.*'}, 'Select a Video File');
        
            % Check if the user selected a file
            if isequal(file, 0)
                disp('User canceled the selection.');
                return;
            end

            % Reset the counter for the new session
            app.SavedCrystalCount = 0;
            app.SavedCounterLabel.Text = 'Crystals Saved: 0';
            app.SavedFrameIndex = 0; % Reset the file naming index
        
            % Construct the full file path
            fullPath = fullfile(path, file);
            app.VideoFileName = file; % Store filename for saving later
        
            % Create a VideoReader object and store it in the app's properties
            app.VideoObject = VideoReader(fullPath);
            app.TotalFrames = app.VideoObject.NumFrames;
        
            % Configure the slider
            app.FrameSlider.Limits = [1 app.TotalFrames];
            app.FrameSlider.Value = 1;
            app.FrameSlider.MajorTicks = round(linspace(1, app.TotalFrames, 10)); % Optional: makes slider look nice
        
            % Read and display the first frame
            currentFrame = readFrame(app.VideoObject);
            imshow(currentFrame, 'Parent', app.UIAxes);
            app.FrameLabel.Text = ['Frame: 1 / ' num2str(app.TotalFrames)];
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
                    % Get the current value of the slider (frame number)
            frameNumber = round(app.FrameSlider.Value);
        
            % Ensure the video object exists
            if ~isempty(app.VideoObject)
                % Read the specific frame
                currentFrame = read(app.VideoObject, frameNumber);
                % Display the frame
                imshow(currentFrame, 'Parent', app.UIAxes);
                % Update the label
                app.FrameLabel.Text = ['Frame: ' num2str(frameNumber) ' / ' num2str(app.TotalFrames)];
            end
        end

        % Button pushed function: CropCrystalButton
        function CropCrystalButtonPushed(app, event)

           
        % Check if an image is loaded
        if isempty(app.UIAxes.Children)
            uialert(app.UIFigure, 'Please load a video first.', 'No Image');
            return;
        end
        
        % Deactivate built-in zoom/pan to prevent conflicts
        zoom(app.UIAxes, 'off');
        pan(app.UIAxes, 'off');
        
        % Use drawpolyline for older MATLAB versions.
        newPolygon = drawpolyline(app.UIAxes);
        
        % Store the completed polyline object in our array
        app.PolygonROIs = [app.PolygonROIs; newPolygon];
        
        % Enable the other buttons now that a selection has been made
        app.ConfirmSelectionButton.Enable = 'on';
        app.ResetPointsButton.Enable = 'on';
     
        end

        % Button pushed function: ConfirmSelectionButton
        function ConfirmSelectionButtonPushed(app, event)

        % 1. Check if at least one crystal has been selected
        if isempty(app.PolygonROIs)
            uialert(app.UIFigure, 'You must select at least one crystal first.', 'No Selections');
            return;
        end

        % --- FIX: Validate that every selection has exactly 4 points ---
        for k = 1:numel(app.PolygonROIs)
            if size(app.PolygonROIs(k).Position, 1) ~= 4
                msg = sprintf('Error: Selection %d has %d points, but must have exactly 4. Please click "Reset All Selections" and try again.', ...
                              k, size(app.PolygonROIs(k).Position, 1));
                uialert(app.UIFigure, msg, 'Invalid Point Count');
                return; % Stop the function here
            end
        end
        % --- End of validation ---

        % 2. Get the current frame's data and dimensions
        hImage = findobj(app.UIAxes, 'Type', 'image');
        currentImageData = hImage.CData;
        [height, width, ~] = size(currentImageData);
        
        % 3. Generate filenames based on the frame number
        currentFrameNumber = round(app.FrameSlider.Value);
        baseFileName = sprintf('Frame_%03d', currentFrameNumber);
        imageSavePath = fullfile(app.OutputFolderPath, [baseFileName, '.png']);
        textSavePath = fullfile(app.OutputFolderPath, [baseFileName, '.txt']);
    
        % 4. Open the text file for writing
        fileID = fopen(textSavePath, 'w');
        if fileID == -1
            uialert(app.UIFigure, 'Error: Could not open file for writing.', 'File Error');
            return;
        end
        
        % 5. Loop through EACH polygon (each crystal)
        for k = 1:numel(app.PolygonROIs)
            % Get the 4 vertex positions for the current polygon
            positions = app.PolygonROIs(k).Position; % This is a 4x2 matrix [x, y]
            
            % Flatten and normalize coordinates
            normalizedCoords = zeros(1, 8);
            for i = 1:4
                normX = positions(i, 1) / width;
                normY = positions(i, 2) / height;
                normalizedCoords((i-1)*2 + 1) = max(0, min(1, normX));
                normalizedCoords((i-1)*2 + 2) = max(0, min(1, normY));
            end
            
            % Write one line to the file for this crystal
            classID = 0;
            fprintf(fileID, '%d %.6f %.6f %.6f %.6f %.6f %.6f %.6f %.6f\n', classID, normalizedCoords);
        end
        
        % 6. Close the text file
        fclose(fileID);
    
        % 7. Save the single image frame
        imwrite(currentImageData, imageSavePath);
    
        % 8. Update counter and provide feedback
        numSaved = numel(app.PolygonROIs);
        app.SavedCrystalCount = app.SavedCrystalCount + numSaved;
        app.SavedCounterLabel.Text = ['Crystals Saved: ' num2str(app.SavedCrystalCount)];
        msgbox([num2str(numSaved) ' crystal annotations saved to ' baseFileName], 'Success');
        
        % 9. Automatically reset for the next frame
        ResetPointsButtonPushed(app, event);
        end

        % Button pushed function: PreviousFrameButton
        function PreviousFrameButtonPushed(app, event)
       
    newValue = app.FrameSlider.Value - 1;
    % Check if we are not below the first frame
    if newValue >= 1
        app.FrameSlider.Value = newValue;
    end
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
          
    newValue = app.FrameSlider.Value + 1;
    % Check if we are not past the last frame
    if newValue <= app.FrameSlider.Limits(2)
        app.FrameSlider.Value = newValue;
    end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
    
        % Open a dialog to let the user select a folder
        % uigetdir starts in the current directory (pwd)
        selectedPath = uigetdir(pwd, 'Select an Output Folder');
        
        % Check if the user selected a folder (and didn't cancel)
        if selectedPath ~= 0
            % Store the path in our property
            app.OutputFolderPath = selectedPath;
            % Update the read-only text field to show the new path
            app.OutputFolderField.Value = app.OutputFolderPath;
        else
            % User canceled, so we do nothing and keep the old path
            disp('Folder selection canceled.');
        end
        end

        % Button pushed function: ResetPointsButton
        function ResetPointsButtonPushed(app, event)
    
        % Loop through and delete all visible polygon objects
        for i = 1:numel(app.PolygonROIs)
            if isvalid(app.PolygonROIs(i))
                delete(app.PolygonROIs(i));
            end
        end
        
        % Reset the property array
        app.PolygonROIs = [];
        
        % Disable the confirm button until a new selection is made
        app.ConfirmSelectionButton.Enable = 'off';
        
        disp('All selections for this frame have been reset.');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Video')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.CropCrystalButton = uibutton(app.UIFigure, 'push');
            app.CropCrystalButton.ButtonPushedFcn = createCallbackFcn(app, @CropCrystalButtonPushed, true);
            app.CropCrystalButton.Text = {'Select Crystal '; '(4 Points)'};
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = {'Frame: '; '1'};
            app.ConfirmSelectionButton = uibutton(app.UIFigure, 'push');
            app.ConfirmSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmSelectionButtonPushed, true);
            app.ConfirmSelectionButton.Text = {'Confirm'; 'Selection'};
            app.PreviousFrameButton = uibutton(app.UIFigure, 'push');
            app.PreviousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFrameButtonPushed, true);
            app.PreviousFrameButton.Text = 'Previous Frame';
            app.NextFrameButton = uibutton(app.UIFigure, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame ';
            app.SavedCounterLabel = uilabel(app.UIFigure);
            app.SavedCounterLabel.Text = 'Frames Saved: 0';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Output Folder';
            app.SavingFolderLabel = uilabel(app.UIFigure);
            app.SavingFolderLabel.Text = 'Saving Folder:';
            app.OutputFolderField = uieditfield(app.UIFigure, 'text');
            app.OutputFolderField.Editable = 'off';
            app.ResetPointsButton = uibutton(app.UIFigure, 'push');
            app.ResetPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPointsButtonPushed, true);
            app.ResetPointsButton.Text = {'Reset All '; 'Selections'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrystalCropperGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end